classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab_2                     matlab.ui.container.Tab
        SlnlpEditField                 matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        KtqunghimEditField             matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel        matlab.ui.control.Label
        NhpphngtrnhEditField           matlab.ui.control.NumericEditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        SaischophpEditField            matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel       matlab.ui.control.Label
        PhngphptmnghimDropDown         matlab.ui.control.DropDown
        PhngphptmnghimDropDownLabel    matlab.ui.control.Label
        KhongphnlynghimEditField       matlab.ui.control.NumericEditField
        KhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        KtqunisuyEditField             matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel        matlab.ui.control.Label
        KtquathcnisuyEditField_2       matlab.ui.control.NumericEditField
        KtquathcnisuyEditField_2Label  matlab.ui.control.Label
        KtquathcnisuyEditField         matlab.ui.control.NumericEditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        NhpdliuxEditField              matlab.ui.control.NumericEditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        PhngphpnisuyDropDownLabel      matlab.ui.control.Label
        PhngphpnisuyDropDown           matlab.ui.control.DropDown
        NhpdliuyEditField              matlab.ui.control.NumericEditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        KtqadonEditField               matlab.ui.control.NumericEditField
        KtqadonEditFieldLabel          matlab.ui.control.Label
        NhpgitrcndonEditField          matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        KtquathchiquyEditField         matlab.ui.control.NumericEditField
        KtquathchiquyEditFieldLabel    matlab.ui.control.Label
        NhpdliuxEditField_2            matlab.ui.control.NumericEditField
        NhpdliuxEditField_2Label       matlab.ui.control.Label
        PhngphphiquyDropDownLabel      matlab.ui.control.Label
        PhngphphiquyDropDown           matlab.ui.control.DropDown
        NhpdliuyEditField_2            matlab.ui.control.NumericEditField
        NhpdliuyEditField_2Label       matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        KtquEditField                  matlab.ui.control.NumericEditField
        KtquEditFieldLabel             matlab.ui.control.Label
        GitrcntnhohmEditField          matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel     matlab.ui.control.Label
        PhngphpohmDropDown             matlab.ui.control.DropDown
        PhngphpohmDropDownLabel        matlab.ui.control.Label
        GitrsaisDropDown               matlab.ui.control.DropDown
        GitrsaisDropDownLabel          matlab.ui.control.Label
        NhphmsEditField                matlab.ui.control.NumericEditField
        NhphmsLabel_2                  matlab.ui.control.Label
        NhpbchEditField                matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        NhpdliuxEditField_3            matlab.ui.control.NumericEditField
        NhpdliuxEditField_3Label       matlab.ui.control.Label
        NhpdliuyEditField_3            matlab.ui.control.NumericEditField
        NhpdliuyEditField_3Label       matlab.ui.control.Label
        TchphnTab                      matlab.ui.container.Tab
        NhphmsEditField_2Label_2       matlab.ui.control.Label
        integral_x                     matlab.ui.control.EditField
        NhpdliuxEditField_4Label_2     matlab.ui.control.Label
        integral_y                     matlab.ui.control.EditField
        integral_fx                    matlab.ui.control.EditField
        integral_method                matlab.ui.control.DropDown
        integral_lower_limit           matlab.ui.control.NumericEditField
        NhpcndiEditFieldLabel          matlab.ui.control.Label
        integral_upper_limit           matlab.ui.control.NumericEditField
        NhpcntrnEditFieldLabel         matlab.ui.control.Label
        NhphmsEditField_2Label         matlab.ui.control.Label
        PhngphptchphnDropDownLabel     matlab.ui.control.Label
        integral_n                     matlab.ui.control.NumericEditField
        integral                       matlab.ui.control.NumericEditField
        KtquEditField_2Label           matlab.ui.control.Label
        NhpNEditFieldLabel             matlab.ui.control.Label
        GiithiunhmTab                  matlab.ui.container.Tab
    end

    
    methods (Access = private)

        function vec = string2Vector(~, str)
            str = erase(str, {'[', ']'});
            str = split(str, {' ', ',', ';'});
            vec = str2double(str);
            vec = vec(~isnan(vec));
        end

        % Hàm tính tích phân hình thang từ các giá trị x và y
        function integral = trapezoidal_integration_from_xy(~, x, y)
            y = str2func(['@(x)', y]);
            y = y(x);

            if length(x) ~= length(y)
                error('Mảng x và y phải có cùng kích thước.');
            end
            
            h = diff(x);
    
            integral = sum(h .* (y(1:end-1) + y(2:end)) / 2);
        end
        
        % Hàm tính tích phân bằng công thức hình thang
        function integral = trapezoidal_integration(~, f, a, b, n)
            h = (b - a) / n;
 
            x = a:h:b;
            y = f(x);
    
            integral = (h / 2) * (y(1) + 2 * sum(y(2:end-1)) + y(end));
        end

        % Hàm tính tích phân bằng công thức Simpson 1/3
        function integral = simpson_13_integration(~, f, a, b, n)
            if mod(n, 2) ~= 0
                errordlg('Số đoạn chia n phải là số chẵn.');
                error('Số đoạn chia n phải là số chẵn.');
            end

            h = (b - a) / n;

            x = a:h:b;
            y = f(x);

            integral = (h / 3) * (y(1) + 4 * sum(y(2:2:end-1)) + 2 * sum(y(3:2:end-2)) + y(end));
        end

            % Hàm tính tích phân bằng công thức Simpson 3/8
        function integral = simpson_38_integration(~, f, a, b, n)
            if mod(n, 3) ~= 0
                errordlg('Số đoạn chia n phải là bội số của 3.');
                error('Số đoạn chia n phải là bội số của 3.');
            end

            h = (b - a) / n;

            x = a:h:b;
            y = f(x);

            integral = (3 * h / 8) * (y(1) + ...
                        3 * sum(y(2:3:end-1)) + ...
                        3 * sum(y(3:3:end-1)) + ...
                        2 * sum(y(4:3:end-3)) + ...
                        y(end));
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: integral_method
        function integral_methodValueChanged(app, event)
                
                switch app.integral_method.Value
                case "Hình thang từ x và y"
                    app.integral.Value = trapezoidal_integration_from_xy(app, string2Vector(app, app.integral_x.Value), app.integral_y.Value);
                case "Hình thang"
                    app.integral.Value = trapezoidal_integration(app, str2func(['@(x)', app.integral_fx.Value]), app.integral_lower_limit.Value, app.integral_upper_limit.Value, app.integral_n.Value);
                case "Simpson 1/3"
                    if mod(app.integral_n.Value, 2) ~= 0
                        app.integral_n.Value = app.integral_n.Value + 1;
                    end
                    app.integral.Value = simpson_13_integration(app, str2func(['@(x)', app.integral_fx.Value]), app.integral_lower_limit.Value, app.integral_upper_limit.Value, app.integral_n.Value);
                case "Simpson 3/8"
                    if mod(app.integral_n.Value, 3) == 1
                        app.integral_n.Value = app.integral_n.Value - 1;
                    elseif mod(app.integral_n.Value, 3) == 2
                        app.integral_n.Value = app.integral_n.Value + 1;
                    end
                    app.integral.Value = simpson_38_integration(app, str2func(['@(x)', app.integral_fx.Value]), app.integral_lower_limit.Value, app.integral_upper_limit.Value, app.integral_n.Value);
            end
            
        end

        % Value changed function: integral_n
        function integral_nValueChanged(app, event)
            integral_methodValueChanged(app, event)
        end

        % Value changed function: integral_fx
        function integral_fxValueChanged(app, event)
            app.integral_fx.Value = replace(erase(app.integral_fx.Value, '.'), '^', '.^');
            integral_methodValueChanged(app, event)
        end

        % Value changed function: integral_lower_limit
        function integral_lower_limitValueChanged(app, event)
            integral_methodValueChanged(app, event)
        end

        % Value changed function: integral_upper_limit
        function integral_upper_limitValueChanged(app, event)
            integral_methodValueChanged(app, event)
        end

        % Value changed function: integral_y
        function integral_yValueChanged(app, event)
            app.integral_y.Value = replace(replace(erase(app.integral_y.Value, '.'), '^', '.^'), '/', '\');
            integral_methodValueChanged(app, event)
        end

        % Value changed function: integral_x
        function integral_xValueChanged(app, event)
            integral_methodValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab_2 = uitab(app.TabGroup);
            app.NghimTab_2.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.KhongphnlynghimEditFieldLabel = uilabel(app.NghimTab_2);
            app.KhongphnlynghimEditFieldLabel.Text = 'Khoảng phân ly nghiệm';
            app.KhongphnlynghimEditField = uieditfield(app.NghimTab_2, 'numeric');
            app.PhngphptmnghimDropDownLabel = uilabel(app.NghimTab_2);
            app.PhngphptmnghimDropDownLabel.Text = 'Phương pháp tìm nghiệm';
            app.PhngphptmnghimDropDown = uidropdown(app.NghimTab_2);
            app.PhngphptmnghimDropDown.Items = {'Chia đôi', 'Lặp', 'Tuyến tính'};
            app.PhngphptmnghimDropDown.Value = 'Chia đôi';
            app.SaischophpEditFieldLabel = uilabel(app.NghimTab_2);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.SaischophpEditField = uieditfield(app.NghimTab_2, 'numeric');
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab_2);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab_2, 'numeric');
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab_2);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab_2, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab_2);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab_2, 'numeric');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.PhngphpnisuyDropDown = uidropdown(app.NisuyTab);
            app.PhngphpnisuyDropDown.Items = {'Newton', 'Lagrange', ''};
            app.PhngphpnisuyDropDown.Value = 'Newton';
            app.PhngphpnisuyDropDownLabel = uilabel(app.NisuyTab);
            app.PhngphpnisuyDropDownLabel.Text = 'Phương pháp nội suy';
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtquathcnisuyEditField_2Label = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditField_2Label.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField_2 = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'numeric');
            app.PhngphphiquyDropDown = uidropdown(app.HiquyTab);
            app.PhngphphiquyDropDown.Items = {'Tuyến tính', 'Mũ', 'Logarit', ''};
            app.PhngphphiquyDropDown.Value = 'Tuyến tính';
            app.PhngphphiquyDropDownLabel = uilabel(app.HiquyTab);
            app.PhngphphiquyDropDownLabel.Text = 'Phương pháp hồi quy';
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'numeric');
            app.KtquathchiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquathchiquyEditFieldLabel.Text = 'Kết quả đa thức hồi quy';
            app.KtquathchiquyEditField = uieditfield(app.HiquyTab, 'numeric');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtqadonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqadonEditFieldLabel.Text = 'Kết qủa dự đoán';
            app.KtqadonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField_3 = uieditfield(app.ohmTab, 'numeric');
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField_3 = uieditfield(app.ohmTab, 'numeric');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhpbchEditField = uieditfield(app.ohmTab, 'numeric');
            app.NhphmsLabel_2 = uilabel(app.ohmTab);
            app.NhphmsLabel_2.Text = 'Nhập hàm số';
            app.NhphmsEditField = uieditfield(app.ohmTab, 'numeric');
            app.GitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.GitrsaisDropDownLabel.Text = 'Giá trị sai số';
            app.GitrsaisDropDown = uidropdown(app.ohmTab);
            app.GitrsaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.GitrsaisDropDown.Value = 'O(h)';
            app.PhngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.PhngphpohmDropDownLabel.Text = 'Phương pháp đạo hàm';
            app.PhngphpohmDropDown = uidropdown(app.ohmTab);
            app.PhngphpohmDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.PhngphpohmDropDown.Value = 'Xấp xỉ tiến';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.GitrcntnhohmEditField = uieditfield(app.ohmTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KtquEditField = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.KtquEditField_2Label = uilabel(app.TchphnTab);
            app.KtquEditField_2Label.Text = 'Kết quả';
            app.integral = uieditfield(app.TchphnTab, 'numeric');
            app.integral_n = uieditfield(app.TchphnTab, 'numeric');
            app.integral_n.ValueChangedFcn = createCallbackFcn(app, @integral_nValueChanged, true);
            app.integral_n.Value = 1;
            app.PhngphptchphnDropDownLabel = uilabel(app.TchphnTab);
            app.PhngphptchphnDropDownLabel.Text = 'Phương pháp tích phân';
            app.NhphmsEditField_2Label = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số';
            app.NhpcntrnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntrnEditFieldLabel.Text = 'Nhập cận trên';
            app.integral_upper_limit = uieditfield(app.TchphnTab, 'numeric');
            app.integral_upper_limit.ValueChangedFcn = createCallbackFcn(app, @integral_upper_limitValueChanged, true);
            app.integral_upper_limit.Value = 1;
            app.NhpcndiEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcndiEditFieldLabel.Text = 'Nhập cận dưới';
            app.integral_lower_limit = uieditfield(app.TchphnTab, 'numeric');
            app.integral_lower_limit.ValueChangedFcn = createCallbackFcn(app, @integral_lower_limitValueChanged, true);
            app.integral_method = uidropdown(app.TchphnTab);
            app.integral_method.Items = {'Hình thang từ x và y', 'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.integral_method.ValueChangedFcn = createCallbackFcn(app, @integral_methodValueChanged, true);
            app.integral_method.Value = 'Hình thang từ x và y';
            app.integral_fx = uieditfield(app.TchphnTab, 'text');
            app.integral_fx.ValueChangedFcn = createCallbackFcn(app, @integral_fxValueChanged, true);
            app.integral_fx.Value = 'x';
            app.integral_y = uieditfield(app.TchphnTab, 'text');
            app.integral_y.ValueChangedFcn = createCallbackFcn(app, @integral_yValueChanged, true);
            app.integral_y.Value = 'x';
            app.NhpdliuxEditField_4Label_2 = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label_2.Text = 'Nhập dữ liệu x';
            app.integral_x = uieditfield(app.TchphnTab, 'text');
            app.integral_x.ValueChangedFcn = createCallbackFcn(app, @integral_xValueChanged, true);
            app.integral_x.Value = '[ ]';
            app.NhphmsEditField_2Label_2 = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label_2.Text = 'Nhập dữ liệu y';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end